classdef UWIH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        BrowseButton  matlab.ui.control.Button
        UIAxes_2      matlab.ui.control.UIAxes
        UIAxes        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            %% Image input
% We take a RGB image as input and convert it to grayscale and store it in
% another variable, so we can get the mean luminance.

 
    [filename, pathname] = uigetfile('*.*', 'Pick a MATLAB code file');
    if isequal(filename,0) || isequal(pathname,0)
       disp('User pressed cancel')
    else
       filename = imread(strcat(pathname, filename));
    end
rgbImage=im2double(filename);
% grayImage = rgb2gray(rgbImage); 
%% White Balancing
% Extract the individual red, green, and blue color channels.
axes(app.UIAxes)
imshow(rgbImage);
x =434;
y= 939;
gray_val = impixel(rgbImage,x,y);
drawpoint('position',[x,y],'color','g');
rgbImage_white_balance = chromadapt(rgbImage,gray_val);
figure;
axes(app.UIAxes_2)
imshow(rgbImage_white_balance)
title('After White balance');

%% Gamma Correction and sharpening
I = imadjust(rgbImage_white_balance,[],[],0.5);
            
J=(rgbImage_white_balance+(rgbImage_white_balance-imgaussfilt(rgbImage_white_balance)));
% 
% figure('Name','Step I-III');
% subplot(221);
% imshow(rgbImage);
% title('Original Image');
% 
% subplot(222);
% imshow(rgbImage_white_balance);
% title('I. White Balance');
% 
% subplot(223);
% imshow(I);
% title('II. Gamma Corrected');
% subplot(224);
% imshow(J);
% title('III. Sharpened');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'input Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Enhanced Image')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UWIH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end